classdef sae_j1772_WebApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusLabel                   matlab.ui.control.Label
        GridLayout                    matlab.ui.container.GridLayout
        enHLCLamp                     matlab.ui.control.Lamp
        EnableHighLevelCommunicationLampLabel  matlab.ui.control.Label
        maxCurrentEditField           matlab.ui.control.NumericEditField
        MaxCurrentEditFieldLabel      matlab.ui.control.Label
        evReadyLamp                   matlab.ui.control.Lamp
        EVReadyLampLabel              matlab.ui.control.Label
        ckStatus                      matlab.ui.control.Button
        GridLayout5                   matlab.ui.container.GridLayout
        batteryCurrentEditField       matlab.ui.control.NumericEditField
        BatteryCurrentEditFieldLabel  matlab.ui.control.Label
        switchEVReady                 matlab.ui.control.Switch
        EVReadyLabel                  matlab.ui.control.Label
        dutyEditField                 matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel       matlab.ui.control.Label
        proxVEditField                matlab.ui.control.NumericEditField
        ProximitryVoltageEditFieldLabel  matlab.ui.control.Label
        pilotVEditField               matlab.ui.control.NumericEditField
        PilotVoltageEditFieldLabel    matlab.ui.control.Label
        statusLabel                   matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        stepsizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        slTimeEditField               matlab.ui.control.NumericEditField
        slTimeEditFieldLabel          matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        slStatesEditField             matlab.ui.control.EditField
        slEditFieldLabel              matlab.ui.control.Label
        TerminateButton               matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        ResumeButton                  matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StepButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
        titleLabel                    matlab.ui.control.Label
    end

    properties (Access = public)
        cView
        cModel
        cControl
    end  

    methods (Access = private)
        
        function init(app)
            app.cView = cSAEJ1772V();
            app.cModel = cSAEJ1772M();
            app.cControl = cSAEJ1772C();

            app.cView.init(app, app.cControl, app.cModel);
            app.cModel.init(app, app.cView, app.cControl);
            app.cControl.init(app, app.cView, app.cModel);

        end

        function update(app)
            app.cModel.update();
            app.cControl.update();
            app.cView.update();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed(), delete(timerfindall); end  % Matlab환경에서 이전에 실행한 내용이 남아 있으면 삭제한다.
            app.init();
            app.update();
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.cControl.clickSimulationInitialize();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.cControl.clickSimulationStart();
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            app.cControl.clickSimulationStep();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.cControl.clickSimulationPause();
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.cControl.clickSimulationResume();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.cControl.clickSimulationStop();
        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            app.cControl.clickSimulationTerminate();
        end

        % Button pushed function: ckStatus
        function ckStatusButtonPushed(app, event)
            app.cView.update();
        end

        % Value changed function: stepsizeEditField
        function stepsizeEditFieldValueChanged(app, event)
            value = app.stepsizeEditField.Value;
            app.cControl.setStepSize(value);
        end

        % Value changed function: pilotVEditField
        function pilotVEditFieldValueChanged(app, event)
            value = app.pilotVEditField.Value;
            app.cControl.setInputParameter("PilotV", value);
        end

        % Value changed function: proxVEditField
        function proxVEditFieldValueChanged(app, event)
            value = app.proxVEditField.Value;
            app.cControl.setInputParameter("ProxV", value);
        end

        % Value changed function: dutyEditField
        function dutyEditFieldValueChanged(app, event)
            value = app.dutyEditField.Value;
            app.cControl.setInputParameter("DutyCycle", value);
        end

        % Value changed function: switchEVReady
        function switchEVReadyValueChanged(app, event)
            value = app.switchEVReady.Value;
            app.cControl.setInputParameter("EVReady", value);
        end

        % Value changed function: batteryCurrentEditField
        function batteryCurrentEditFieldValueChanged(app, event)
            value = app.batteryCurrentEditField.Value;
            app.cControl.setInputParameter("BatteryC", value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.cControl);app.cControl = [];
            delete(app.cModel);app.cModel = [];
            delete(app.cView);app.cView = [];
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.titleLabel = uilabel(app.GridLayout);
            app.titleLabel.Text = 'SAE J1772 Status Simulation';
            app.InitializeButton = uibutton(app.GridLayout, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StepButton = uibutton(app.GridLayout, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.GridLayout, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TerminateButton = uibutton(app.GridLayout, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.slEditFieldLabel = uilabel(app.GridLayout2);
            app.slEditFieldLabel.Text = 'Status';
            app.slStatesEditField = uieditfield(app.GridLayout2, 'text');
            app.slStatesEditField.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.slTimeEditFieldLabel = uilabel(app.GridLayout3);
            app.slTimeEditFieldLabel.Text = 'Simulation Time';
            app.slTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.slTimeEditField.Editable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.stepsizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.stepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @stepsizeEditFieldValueChanged, true);
            app.stepsizeEditField.Value = 0.1;
            app.statusLabel = uilabel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.PilotVoltageEditFieldLabel = uilabel(app.GridLayout5);
            app.PilotVoltageEditFieldLabel.Text = 'Pilot Voltage';
            app.pilotVEditField = uieditfield(app.GridLayout5, 'numeric');
            app.pilotVEditField.ValueChangedFcn = createCallbackFcn(app, @pilotVEditFieldValueChanged, true);
            app.ProximitryVoltageEditFieldLabel = uilabel(app.GridLayout5);
            app.ProximitryVoltageEditFieldLabel.Text = 'Proximitry Voltage';
            app.proxVEditField = uieditfield(app.GridLayout5, 'numeric');
            app.proxVEditField.ValueChangedFcn = createCallbackFcn(app, @proxVEditFieldValueChanged, true);
            app.DutyCycleEditFieldLabel = uilabel(app.GridLayout5);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.dutyEditField = uieditfield(app.GridLayout5, 'numeric');
            app.dutyEditField.ValueChangedFcn = createCallbackFcn(app, @dutyEditFieldValueChanged, true);
            app.EVReadyLabel = uilabel(app.GridLayout5);
            app.EVReadyLabel.Text = 'Vehicle OK';
            app.switchEVReady = uiswitch(app.GridLayout5, 'slider');
            app.switchEVReady.ValueChangedFcn = createCallbackFcn(app, @switchEVReadyValueChanged, true);
            app.BatteryCurrentEditFieldLabel = uilabel(app.GridLayout5);
            app.BatteryCurrentEditFieldLabel.Text = 'Battery Current';
            app.batteryCurrentEditField = uieditfield(app.GridLayout5, 'numeric');
            app.batteryCurrentEditField.ValueChangedFcn = createCallbackFcn(app, @batteryCurrentEditFieldValueChanged, true);
            app.ckStatus = uibutton(app.GridLayout, 'push');
            app.ckStatus.ButtonPushedFcn = createCallbackFcn(app, @ckStatusButtonPushed, true);
            app.ckStatus.Text = 'Update';
            app.EVReadyLampLabel = uilabel(app.GridLayout);
            app.EVReadyLampLabel.Text = 'EV Ready';
            app.evReadyLamp = uilamp(app.GridLayout);
            app.MaxCurrentEditFieldLabel = uilabel(app.GridLayout);
            app.MaxCurrentEditFieldLabel.Text = 'Max Current';
            app.maxCurrentEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnableHighLevelCommunicationLampLabel = uilabel(app.GridLayout);
            app.EnableHighLevelCommunicationLampLabel.Text = 'Enable High Level Communication';
            app.enHLCLamp = uilamp(app.GridLayout);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sae_j1772_WebApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end