classdef sae_j1772_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        StopTimeEditField              matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel         matlab.ui.control.Label
        EVSAEJ1772StateSimulatorLabel  matlab.ui.control.Label
        VariableTuner                  simulink.ui.control.VariableTuner
        TimeScope6                     matlab.ui.scope.TimeScope
        TimeScope5                     matlab.ui.scope.TimeScope
        TimeScope4                     matlab.ui.scope.TimeScope
        TimeScope3                     matlab.ui.scope.TimeScope
        TimeScope2                     matlab.ui.scope.TimeScope
        TimeScope                      matlab.ui.scope.TimeScope
        SimulationProgress             simulink.ui.control.SimulationProgress
        SimulationControls             simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        lTimer % Description
    end
    
    methods (Access = private)
        
        % Timer 실행 함수 정의
        function TimerStartFunc(app, src, ~)
            % app.Simulation
        end

        function TimerMainFunc(app, src, ~)

        end

        function TimerErrorFunc(app, src, ~)

        end

        function TimerStopFunc(app, src, ~)
            % app.Simulation
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = app.Simulation.getModelParameter('StopTime');
            app.StopTimeEditField.Value = str2num(p.Value);
            app.Simulation.setModelParameter('SolverType', "Fixed-step");
            
            app.StopTimeEditField.Value = 10;
            app.Simulation.setModelParameter('StopTime', num2str(app.StopTimeEditField.Value));
            app.Simulation.setModelParameter('EnablePacing', 'on');
            app.Simulation.setModelParameter('PacingRate', 0.1);            
            
            % sig = app.Simulation.LoggedSignals;
            % varName = app.Simulation.ModelName + "/SAE J1772 AC Charging (EV):1";
            % bind(sig, varName, app.ListBox, "Value");

            movegui(app.UIFigure, "center");

            hTimer = timer('Name', 'ReadATF' );
            set(hTimer,'ExecutionMode','fixedSpacing','StartDelay',1);
            set(hTimer,'period',0.5);
            set(hTimer,'BusyMode','drop');
            set(hTimer, ...
                'StartFcn', @(src, ev)app.TimerStartFunc(src, ev),...
                'TimerFcn', @(src, ev)app.TimerMainFunc(src, ev),...
                'ErrorFcn', @(src, ev)app.TimerErrorFunc(src, ev), ...
                'StopFcn',  @(src, ev)app.TimerStopFunc(src, ev));

            app.lTimer = hTimer;
            start(app.lTimer);
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            value = app.StopTimeEditField.Value;
            app.Simulation.setModelParameter('StopTime', num2str(value));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isa(app.lTimer, "timer") && strcmp(app.lTimer.Running, 'on')
                stop(app.lTimer);
            end
            delete(app.lTimer);
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SimulationControls = uisimcontrols(app.GridLayout);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.GridLayout);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.GridLayout);
            app.TimeScope.Title = 'EV State AC';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope2 = uitimescope(app.GridLayout);
            app.TimeScope2.LegendNames = {'Cast To Double1:1'};
            app.TimeScope2.Title = 'Enable HLC';
            app.TimeScope2.YLabel = 'Amplitude';
            app.TimeScope3 = uitimescope(app.GridLayout);
            app.TimeScope3.LegendNames = {'Cast To Double2:1'};
            app.TimeScope3.Title = 'EV Ready';
            app.TimeScope3.YLabel = 'Amplitude';
            app.TimeScope4 = uitimescope(app.GridLayout);
            app.TimeScope4.LegendNames = {'Cast To Double3:1'};
            app.TimeScope4.Title = 'Maximum Current';
            app.TimeScope4.YLabel = 'Amplitude';
            app.TimeScope5 = uitimescope(app.GridLayout);
            app.TimeScope5.LegendNames = {'Cast To Double4:1'};
            app.TimeScope5.Title = 'Count Command';
            app.TimeScope5.YLabel = 'Amplitude';
            app.TimeScope6 = uitimescope(app.GridLayout);
            app.TimeScope6.Title = 'Title';
            app.TimeScope6.YLabel = 'Amplitude';
            app.VariableTuner = uisimvartuner(app.GridLayout);
            app.VariableTuner.Simulation = app.Simulation;
            app.EVSAEJ1772StateSimulatorLabel = uilabel(app.GridLayout);
            app.EVSAEJ1772StateSimulatorLabel.Text = 'EV 측 SAE J1772 State Simulator';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StopTimeEditFieldLabel = uilabel(app.GridLayout2);
            app.StopTimeEditFieldLabel.Text = 'StopTime';
            app.StopTimeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Cast To Double:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Cast To Double1:1'], app.TimeScope2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Cast To Double2:1'], app.TimeScope3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Cast To Double3:1'], app.TimeScope4);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Cast To Double4:1'], app.TimeScope5);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sae_j1772_app

            % Associate the Simulink Model
            app.Simulation = simulation('sae_j1772');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end